classdef min_max_finder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PresisionSpinner          matlab.ui.control.Spinner
        PresisionSpinnerLabel     matlab.ui.control.Label
        BackButton                matlab.ui.control.Button
        DrawButton                matlab.ui.control.Button
        MinimizeCheckBox          matlab.ui.control.CheckBox
        ErrorLabel                matlab.ui.control.Label
        MutationrateSpinner       matlab.ui.control.Spinner
        MutationrateSpinnerLabel  matlab.ui.control.Label
        FunctionEditField         matlab.ui.control.EditField
        FunctionEditFieldLabel    matlab.ui.control.Label
        MaxxSpinner               matlab.ui.control.Spinner
        MaxxSpinnerLabel          matlab.ui.control.Label
        MinxSpinner               matlab.ui.control.Spinner
        MinxSpinnerLabel          matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        PopsizeSpinner            matlab.ui.control.Spinner
        PopsizeSpinnerLabel       matlab.ui.control.Label
        CrossoverDropDown         matlab.ui.control.DropDown
        CrossoverDropDownLabel    matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        gen_alg % Binary genetic algorithm class
        not_run % Whether genetic algorithm has been successfully run yet
    end
    
    methods (Access = private)
        
        function validateRange(app)

            if isempty(app.MinxSpinner.Value)
                min_val = intmin('int32');
            else
                min_val = app.MinxSpinner.Value;
            end

            if isempty(app.MaxxSpinner.Value)
                max_val = intmax('int32');
            else
                max_val = app.MaxxSpinner.Value;
            end

            if min_val < intmin('int32')
                app.ErrorLabel.Text = "Value too small";
                app.RunButton.Enable = 'off';
            elseif max_val > intmax('int32')
                app.ErrorLabel.Text = "Value too big";
                app.RunButton.Enable = 'off';
            elseif min_val > max_val
                app.ErrorLabel.Text = "Min value can't be greater than max";
                app.RunButton.Enable = 'off';
            else
                app.ErrorLabel.Text = '';
                app.RunButton.Enable = 'on';
            end
        end

        function plotfun(app)
            mini = app.MinxSpinner.Value;
            maxi = app.MaxxSpinner.Value;
            func = app.validateAndReturnFunc(app.FunctionEditField.Value);
            if nargin(func) == 1
                x_values = linspace(mini, maxi, 1000);
                y_values = arrayfun(func, x_values);

                plot(app.UIAxes, x_values, y_values, 'LineWidth', 1.5, ...
                    'Color', 'magenta');

                val_range = max(y_values) - min(y_values) + 1;
                val_range = val_range * 0.1;

                ylim(app.UIAxes, [ ...
                    min(y_values) - val_range, ...
                    max(y_values) + val_range])

                fstr = func2str(func);
                title(app.UIAxes, "f(x) = " + fstr(5:end))

                lgd = legend(app.UIAxes);
                lgd.Visible = 'off';

                xlabel(app.UIAxes,'x')
                ylabel(app.UIAxes,'f(x)')
            end            
        end

        function current_func = validateAndReturnFunc(app, func_str)
            try
                current_func = str2func(func_str);
                current_func(0);
            catch exception
                app.ErrorLabel.Text = "Function: " + exception.message;
                return
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gen_alg = gen_alg_bin();
            app.not_run = true;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cla(app.UIAxes, 'reset')
            app.ErrorLabel.Text = "";

            %Get ga options
            current_crossover = app.CrossoverDropDown.Value;
            current_mutation_rate = app.MutationrateSpinner.Value;
            current_pop_size = app.PopsizeSpinner.Value;

            app.gen_alg.set_options( ...
                'CrossoverFcn', current_crossover, ...
                'MutationFcn', {@mutationuniform, current_mutation_rate}, ...
                'PopulationSize', current_pop_size)

            % Get ga params
            func_str = app.FunctionEditField.Value;
            current_min = app.MinxSpinner.Value;
            current_max = app.MaxxSpinner.Value;
            current_minimize = app.MinimizeCheckBox.Value;
            current_precision = app.PresisionSpinner.Value;
            current_func = app.validateAndReturnFunc(func_str);

            % Run the genetic algorithm 
            app.gen_alg.optimize( ...
                current_func, ...
                current_min, ...
                current_max, ...
                current_minimize, ...
                current_precision, ...
                app.UIAxes);

            app.not_run = false;
        end

        % Value changed function: MaxxSpinner
        function MaxxSpinnerValueChanged(app, event)
            validateRange(app)
        end

        % Value changed function: MinxSpinner
        function MinxSpinnerValueChanged(app, event)
            validateRange(app)
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.plotfun()
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if ~app.not_run
                app.gen_alg.plotfun_metrics()
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CrossoverDropDownLabel = uilabel(app.UIFigure);
            app.CrossoverDropDownLabel.Text = 'Crossover';
            app.CrossoverDropDown = uidropdown(app.UIFigure);
            app.CrossoverDropDown.Items = {'Single point', 'Two point'};
            app.CrossoverDropDown.ItemsData = {'crossoversinglepoint', 'crossovertwopoint'};
            app.CrossoverDropDown.Value = 'crossoversinglepoint';
            app.PopsizeSpinnerLabel = uilabel(app.UIFigure);
            app.PopsizeSpinnerLabel.Text = 'Pop size';
            app.PopsizeSpinner = uispinner(app.UIFigure);
            app.PopsizeSpinner.Limits = [1 Inf];
            app.PopsizeSpinner.RoundFractionalValues = 'on';
            app.PopsizeSpinner.Value = 50;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MinxSpinnerLabel = uilabel(app.UIFigure);
            app.MinxSpinnerLabel.Text = 'Min x';
            app.MinxSpinner = uispinner(app.UIFigure);
            app.MinxSpinner.AllowEmpty = 'on';
            app.MinxSpinner.ValueChangedFcn = createCallbackFcn(app, @MinxSpinnerValueChanged, true);
            app.MinxSpinner.Value = -1;
            app.MaxxSpinnerLabel = uilabel(app.UIFigure);
            app.MaxxSpinnerLabel.Text = 'Max x';
            app.MaxxSpinner = uispinner(app.UIFigure);
            app.MaxxSpinner.AllowEmpty = 'on';
            app.MaxxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxxSpinnerValueChanged, true);
            app.MaxxSpinner.Value = 28;
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.Value = '@(x) -0.2 * x^2 + 5.5 * x + 6';
            app.MutationrateSpinnerLabel = uilabel(app.UIFigure);
            app.MutationrateSpinnerLabel.Text = 'Mutation rate';
            app.MutationrateSpinner = uispinner(app.UIFigure);
            app.MutationrateSpinner.Step = 0.01;
            app.MutationrateSpinner.Limits = [0 1];
            app.MutationrateSpinner.Value = 0.01;
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = '';
            app.MinimizeCheckBox = uicheckbox(app.UIFigure);
            app.MinimizeCheckBox.Text = 'Minimize';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.PresisionSpinnerLabel = uilabel(app.UIFigure);
            app.PresisionSpinnerLabel.Text = 'Presision';
            app.PresisionSpinner = uispinner(app.UIFigure);
            app.PresisionSpinner.Limits = [0 8];
            app.PresisionSpinner.RoundFractionalValues = 'on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = min_max_finder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end